classdef FutureHome < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SmartHome                      matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ImportdatasetMenu              matlab.ui.container.Menu
        LoaddataMenu                   matlab.ui.container.Menu
        ParsedataMenu                  matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        Consulta                       matlab.ui.container.Tab
        Button                         matlab.ui.control.Button
        ExperimentLabel                matlab.ui.control.Label
        ExperienciaDropDown            matlab.ui.control.DropDown
        LightingSwitchLabel            matlab.ui.control.Label
        LightingSwitch                 matlab.ui.control.ToggleSwitch
        lamp1                          matlab.ui.control.Button
        lamp2                          matlab.ui.control.Button
        lamp3                          matlab.ui.control.Button
        lamp4                          matlab.ui.control.Button
        lamp5                          matlab.ui.control.Button
        lamp6                          matlab.ui.control.Button
        lamp7                          matlab.ui.control.Button
        lamp8                          matlab.ui.control.Button
        lamp9                          matlab.ui.control.Button
        lamp10                         matlab.ui.control.Button
        lamp11                         matlab.ui.control.Button
        lamp12                         matlab.ui.control.Button
        lamp13                         matlab.ui.control.Button
        lamp14                         matlab.ui.control.Button
        lamp15                         matlab.ui.control.Button
        OutputTextArea_3Label          matlab.ui.control.Label
        OutputTextArea_3               matlab.ui.control.TextArea
        PowerPlugSwitchLabel           matlab.ui.control.Label
        PowerPlugSwitch                matlab.ui.control.ToggleSwitch
        plug1                          matlab.ui.control.Button
        plug2                          matlab.ui.control.Button
        plug3                          matlab.ui.control.Button
        plug4                          matlab.ui.control.Button
        plug5                          matlab.ui.control.Button
        plug6                          matlab.ui.control.Button
        plug7                          matlab.ui.control.Button
        plug8                          matlab.ui.control.Button
        plug9                          matlab.ui.control.Button
        plug10                         matlab.ui.control.Button
        plug11                         matlab.ui.control.Button
        plug12                         matlab.ui.control.Button
        ShuttersSwitchLabel            matlab.ui.control.Label
        ShuttersSwitch                 matlab.ui.control.ToggleSwitch
        shut1                          matlab.ui.control.Button
        shut2                          matlab.ui.control.Button
        shut3                          matlab.ui.control.Button
        shut4                          matlab.ui.control.Button
        shut5                          matlab.ui.control.Button
        shut6                          matlab.ui.control.Button
        shut7                          matlab.ui.control.Button
        shut8                          matlab.ui.control.Button
        shut9                          matlab.ui.control.Button
        shut10                         matlab.ui.control.Button
        open1                          matlab.ui.control.Button
        open2                          matlab.ui.control.Button
        open3                          matlab.ui.control.Button
        open4                          matlab.ui.control.Button
        open5                          matlab.ui.control.Button
        open6                          matlab.ui.control.Button
        open7                          matlab.ui.control.Button
        open8                          matlab.ui.control.Button
        open9                          matlab.ui.control.Button
        open10                         matlab.ui.control.Button
        open11                         matlab.ui.control.Button
        open12                         matlab.ui.control.Button
        open13                         matlab.ui.control.Button
        open14                         matlab.ui.control.Button
        open15                         matlab.ui.control.Button
        OpeningSwitchLabel             matlab.ui.control.Label
        OpeningSwitch                  matlab.ui.control.ToggleSwitch
        PresenceSwitchLabel            matlab.ui.control.Label
        PresenceSwitch                 matlab.ui.control.ToggleSwitch
        pres1                          matlab.ui.control.Button
        pres2                          matlab.ui.control.Button
        RGBSwitchLabel                 matlab.ui.control.Label
        RGBSwitch                      matlab.ui.control.ToggleSwitch
        rgb1                           matlab.ui.control.Button
        rgb2                           matlab.ui.control.Button
        rgb3                           matlab.ui.control.Button
        rgb4                           matlab.ui.control.Button
        MultimediaSwitchLabel          matlab.ui.control.Label
        MultimediaSwitch               matlab.ui.control.ToggleSwitch
        mult1                          matlab.ui.control.Button
        mult2                          matlab.ui.control.Button
        mult3                          matlab.ui.control.Button
        mult4                          matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        TemperatureGaugeLabel          matlab.ui.control.Label
        TemperatureGauge               matlab.ui.control.Gauge
        HumidityGaugeLabel             matlab.ui.control.Label
        HumidityGauge                  matlab.ui.control.Gauge
        LuminosityGaugeLabel           matlab.ui.control.Label
        LuminosityGauge                matlab.ui.control.Gauge
        VentilationAirSpeedGaugeLabel  matlab.ui.control.Label
        VentilationAirSpeedGauge       matlab.ui.control.Gauge
        SmellGaugeLabel                matlab.ui.control.Label
        SmellGauge                     matlab.ui.control.Gauge
        NoiseLevelGaugeLabel           matlab.ui.control.Label
        NoiseLevelGauge                matlab.ui.control.Gauge
        ThermalComfortGaugeLabel       matlab.ui.control.Label
        ThermalComfortGauge            matlab.ui.control.Gauge
        GlobalComfortGaugeLabel        matlab.ui.control.Label
        GlobalComfortGauge             matlab.ui.control.Gauge
        AirQualityGaugeLabel           matlab.ui.control.Label
        AirQualityGauge                matlab.ui.control.Gauge
        LightingComfortGaugeLabel      matlab.ui.control.Label
        LightingComfortGauge           matlab.ui.control.Gauge
        AcousticComfortGaugeLabel      matlab.ui.control.Label
        AcousticComfortGauge           matlab.ui.control.Gauge
        ShowValuesSwitch_2Label        matlab.ui.control.Label
        ShowValuesSwitch_2             matlab.ui.control.RockerSwitch
        MiscLabel                      matlab.ui.control.Label
        MiscSwitch                     matlab.ui.control.ToggleSwitch
        temp                           matlab.ui.control.Button
        temp_2                         matlab.ui.control.Button
        co2                            matlab.ui.control.Button
        humidity                       matlab.ui.control.Button
        DateDropDownLabel              matlab.ui.control.Label
        DateDropDown                   matlab.ui.control.DropDown
        forwardButton                  matlab.ui.control.Button
        backButton                     matlab.ui.control.Button
        lumT_1                         matlab.ui.control.Label
        lumT_2                         matlab.ui.control.Label
        lumT_3                         matlab.ui.control.Label
        lumT_4                         matlab.ui.control.Label
        lumT_5                         matlab.ui.control.Label
        lumT_6                         matlab.ui.control.Label
        humT_1                         matlab.ui.control.Label
        temT_1                         matlab.ui.control.Label
        temT_2                         matlab.ui.control.Label
        co2T_1                         matlab.ui.control.Label
        Teste                          matlab.ui.container.Tab
        TrainingPanel                  matlab.ui.container.Panel
        OutputTextArea_2Label          matlab.ui.control.Label
        OutputTextArea_2               matlab.ui.control.TextArea
        TemperatureKnob_2Label         matlab.ui.control.Label
        TemperatureKnob_2              matlab.ui.control.DiscreteKnob
        HumidityKnob_3Label            matlab.ui.control.Label
        HumidityKnob_3                 matlab.ui.control.DiscreteKnob
        LuminosityKnob_2Label          matlab.ui.control.Label
        LuminosityKnob_2               matlab.ui.control.DiscreteKnob
        GlobalKnobLabel                matlab.ui.control.Label
        GlobalKnob                     matlab.ui.control.DiscreteKnob
        ThermalKnobLabel               matlab.ui.control.Label
        ThermalKnob                    matlab.ui.control.DiscreteKnob
        LightingKnobLabel              matlab.ui.control.Label
        LightingKnob                   matlab.ui.control.DiscreteKnob
        Label                          matlab.ui.control.Label
        lampLighting                   matlab.ui.control.Lamp
        Lamp_2Label                    matlab.ui.control.Label
        lampTemperature                matlab.ui.control.Lamp
        Lamp_3Label                    matlab.ui.control.Label
        lampHumidity                   matlab.ui.control.Lamp
        Lamp_4Label                    matlab.ui.control.Label
        lampLuminosity                 matlab.ui.control.Lamp
        Lamp_5Label                    matlab.ui.control.Label
        lampGlobal                     matlab.ui.control.Lamp
        Lamp_6Label                    matlab.ui.control.Label
        lampThermal                    matlab.ui.control.Lamp
        acuTemp                        matlab.ui.control.Label
        acuHum                         matlab.ui.control.Label
        acuLum                         matlab.ui.control.Label
        acuGlob                        matlab.ui.control.Label
        acuTherm                       matlab.ui.control.Label
        acuLight                       matlab.ui.control.Label
        TestingparametersPanel         matlab.ui.container.Panel
        TemperatureKnobLabel           matlab.ui.control.Label
        TemperatureKnob                matlab.ui.control.Knob
        HumidityKnob_2Label            matlab.ui.control.Label
        HumidityKnob_2                 matlab.ui.control.Knob
        LuminosityKnobLabel            matlab.ui.control.Label
        LuminosityKnob                 matlab.ui.control.Knob
        testButton                     matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        TemperatureGauge_2Label        matlab.ui.control.Label
        TemperatureGauge_2             matlab.ui.control.Gauge
        HumidityGauge_2Label           matlab.ui.control.Label
        HumidityGauge_2                matlab.ui.control.Gauge
        LuminosityGauge_2Label         matlab.ui.control.Label
        LuminosityGauge_2              matlab.ui.control.Gauge
        VentilationAirSpeedGauge_2Label  matlab.ui.control.Label
        VentilationAirSpeedGauge_2     matlab.ui.control.Gauge
        SmellGauge_2Label              matlab.ui.control.Label
        SmellGauge_2                   matlab.ui.control.Gauge
        NoiseLevelGauge_2Label         matlab.ui.control.Label
        NoiseLevelGauge_2              matlab.ui.control.Gauge
        ThermalComfortGauge_2Label     matlab.ui.control.Label
        ThermalComfortGauge_2          matlab.ui.control.Gauge
        GlobalComfortGauge_2Label      matlab.ui.control.Label
        GlobalComfortGauge_2           matlab.ui.control.Gauge
        AirQualityGauge_2Label         matlab.ui.control.Label
        AirQualityGauge_2              matlab.ui.control.Gauge
        LightingComfortGauge_2Label    matlab.ui.control.Label
        LightingComfortGauge_2         matlab.ui.control.Gauge
        AcousticComfortGauge_2Label    matlab.ui.control.Label
        AcousticComfortGauge_2         matlab.ui.control.Gauge
        TrainButton                    matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        data=[];
        msg='';
        info=[];
        sensor=[];
        last_values=zeros(1,6);
        flag=0;
        
    end
    
    methods (Access = public)
        
        %% Extracts and returns experiments start and end times (Julian)
        % data_source : array with Sensors data
        % exp_idx : experiment column index
        % time_idx : posix colummn index
        % returns [ experiment_id start_time_julian end_time_julian
        % start_time_posix end_time_posix]
        function [out]=getExperimentTimes(data_source,exp_idx,time_idx)
            e = unique( data_source(:,exp_idx));
            exp_times=[];
            for i=1:size(e,1)
                [row_idx, ~] = find(data_source(:, exp_idx) == e(i));
                from_mame=data_source(row_idx,:);
                exp_start = datetime(str2num(char(from_mame(1,time_idx)))/1000,'ConvertFrom', 'posixtime');
                exp_end = datetime(str2num(char(from_mame(end-1,time_idx)))/1000,'ConvertFrom', 'posixtime');
                exp_times = [exp_times; e(i) datestr(exp_start) datestr(exp_end) from_mame(1,time_idx) from_mame(end-1,time_idx)];
            end
            [~,idx]=sort(double(string(exp_times(:,1))));
            out=exp_times(idx,:);
        end
        
        
    end
    
    methods (Access = private)
        
        function updateOuput1(msg)
            cmdWinDoc = com.mathworks.mde.cmdwin.CmdWinDocument.getInstance;
            jString   = cmdWinDoc.getText(cmdWinDoc.getStartPosition.getOffset, ...
                cmdWinDoc.getLength);
            mString = char(jString);
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.data=load("domusdata");
            app.OutputTextArea_3.Value{end+1}='Loading data...';
            %extrair número das experiências
            a=sortrows(str2double(unique(app.data.Sensors_data(:,2))));
            b={};
            for i=1:size(a,1)
                b{end+1}=char(num2str(a(i)));
            end
            
            app.ExperienciaDropDown.Items=b;
            value = app.ExperienciaDropDown.Value;
            %             app.OutputTextArea_3.Value=value;
            ExperienciaDropDownValueChanged(app);
        end

        % Value changed function: LightingSwitch
        function LightingSwitchValueChanged(app, event)
            value = app.LightingSwitch.Value;
            switch value
                case 'Off'
                    app.lamp1.Visible='off';
                    app.lamp2.Visible='off';
                    app.lamp3.Visible='off';
                    app.lamp4.Visible='off';
                    app.lamp5.Visible='off';
                    app.lamp6.Visible='off';
                    app.lamp7.Visible='off';
                    app.lamp8.Visible='off';
                    app.lamp9.Visible='off';
                    app.lamp10.Visible='off';
                    app.lamp11.Visible='off';
                    app.lamp12.Visible='off';
                    app.lamp13.Visible='off';
                    app.lamp14.Visible='off';
                    app.lamp15.Visible='off';
                case 'On'
                    app.lamp1.Visible='on';
                    app.lamp2.Visible='on';
                    app.lamp3.Visible='on';
                    app.lamp4.Visible='on';
                    app.lamp5.Visible='on';
                    app.lamp6.Visible='on';
                    app.lamp7.Visible='on';
                    app.lamp8.Visible='on';
                    app.lamp9.Visible='on';
                    app.lamp10.Visible='on';
                    app.lamp11.Visible='on';
                    app.lamp12.Visible='on';
                    app.lamp13.Visible='on';
                    app.lamp14.Visible='on';
                    app.lamp15.Visible='on';
            end


        end

        % Value changed function: PowerPlugSwitch
        function PowerPlugSwitchValueChanged(app, event)
            value = app.PowerPlugSwitch.Value;
            switch value
                case 'Off'
                    app.plug1.Visible='off';
                    app.plug2.Visible='off';
                    app.plug3.Visible='off';
                    app.plug4.Visible='off';
                    app.plug5.Visible='off';
                    app.plug6.Visible='off';
                    app.plug7.Visible='off';
                    app.plug8.Visible='off';
                    app.plug9.Visible='off';
                    app.plug10.Visible='off';
                    app.plug11.Visible='off';
                    app.plug12.Visible='off';
                case 'On'
                    app.plug1.Visible='on';
                    app.plug2.Visible='on';
                    app.plug3.Visible='on';
                    app.plug4.Visible='on';
                    app.plug5.Visible='on';
                    app.plug6.Visible='on';
                    app.plug7.Visible='on';
                    app.plug8.Visible='on';
                    app.plug9.Visible='on';
                    app.plug10.Visible='on';
                    app.plug11.Visible='on';
                    app.plug12.Visible='on';
            end
        end

        % Value changed function: ShuttersSwitch
        function ShuttersSwitchValueChanged(app, event)
            value = app.ShuttersSwitch.Value;
            switch value
                case 'Off'
                    app.shut1.Visible='off';
                    app.shut2.Visible='off';
                    app.shut3.Visible='off';
                    app.shut4.Visible='off';
                    app.shut5.Visible='off';
                    app.shut6.Visible='off';
                    app.shut7.Visible='off';
                    app.shut8.Visible='off';
                    app.shut9.Visible='off';
                    app.shut10.Visible='off';
                case 'On'
                    app.shut1.Visible='on';
                    app.shut2.Visible='on';
                    app.shut3.Visible='on';
                    app.shut4.Visible='on';
                    app.shut5.Visible='on';
                    app.shut6.Visible='on';
                    app.shut7.Visible='on';
                    app.shut8.Visible='on';
                    app.shut9.Visible='on';
                    app.shut10.Visible='on';
            end
        end

        % Value changed function: OpeningSwitch
        function OpeningSwitchValueChanged(app, event)
            value = app.OpeningSwitch.Value;
            switch value
                case 'Off'
                    app.open1.Visible='off';
                    app.open2.Visible='off';
                    app.open3.Visible='off';
                    app.open4.Visible='off';
                    app.open5.Visible='off';
                    app.open6.Visible='off';
                    app.open7.Visible='off';
                    app.open8.Visible='off';
                    app.open9.Visible='off';
                    app.open10.Visible='off';
                    app.open11.Visible='off';
                    app.open12.Visible='off';
                    app.open13.Visible='off';
                    app.open14.Visible='off';
                    app.open15.Visible='off';
                case 'On'
                    app.open1.Visible='on';
                    app.open2.Visible='on';
                    app.open3.Visible='on';
                    app.open4.Visible='on';
                    app.open5.Visible='on';
                    app.open6.Visible='on';
                    app.open7.Visible='on';
                    app.open8.Visible='on';
                    app.open9.Visible='on';
                    app.open10.Visible='on';
                    app.open11.Visible='on';
                    app.open12.Visible='on';
                    app.open13.Visible='on';
                    app.open14.Visible='on';
                    app.open15.Visible='on';
            end
        end

        % Value changed function: PresenceSwitch
        function PresenceSwitchValueChanged(app, event)
            value = app.PresenceSwitch.Value;
            switch value
                case 'Off'
                    app.pres1.Visible='off';
                    app.pres2.Visible='off';
                    
                case 'On'
                    app.pres1.Visible='on';
                    app.pres2.Visible='on';
            end
        end

        % Value changed function: RGBSwitch
        function RGBSwitchValueChanged(app, event)
            value = app.RGBSwitch.Value;
            switch value
                case 'Off'
                    app.rgb1.Visible='off';
                    app.rgb2.Visible='off';
                    app.rgb3.Visible='off';
                    app.rgb4.Visible='off';
                    
                case 'On'
                    app.rgb1.Visible='on';
                    app.rgb2.Visible='on';
                    app.rgb3.Visible='on';
                    app.rgb4.Visible='on';
            end
        end

        % Value changed function: MultimediaSwitch
        function MultimediaSwitchValueChanged(app, event)
            value = app.MultimediaSwitch.Value;
            switch value
                case 'Off'
                    app.mult1.Visible='off';
                    app.mult2.Visible='off';
                    app.mult3.Visible='off';
                    app.mult4.Visible='off';
                    
                case 'On'
                    app.mult1.Visible='on';
                    app.mult2.Visible='on';
                    app.mult3.Visible='on';
                    app.mult4.Visible='on';
            end
        end

        % Menu selected function: LoaddataMenu
        function LoaddataMenuSelected(app, event)
            t=uigetfile('*.mat');
            app.data=load(t);
            %extrair número das experiências
            a=sortrows(str2double(unique(app.data.Sensors_data(:,2))));
            b={};
            for i=1:size(a,1)
                b{end+1}=char(num2str(a(i)));
            end
            app.ExperienciaDropDown.Items=b;
            value = app.ExperienciaDropDown.Value;
            %             app.OutputTextArea_3.Value=value;
            ExperienciaDropDownValueChanged(app, event);
            
        end

        % Value changed function: ExperienciaDropDown
        function ExperienciaDropDownValueChanged(app, event)
            value = app.ExperienciaDropDown.Value;
            [row_idx, ~] = find(app.data.feedback_dump(:, 1) == value);
            app.info=app.data.feedback_dump(row_idx,:);


            [C,ia,ic] = unique(app.info(:,2),'rows');
            app.info = app.info(ia,:);
            names1 = datestr(datetime(str2double(app.info(:,2))/1000, 'ConvertFrom', 'posixtime'));
            names1 = unique(cellstr(names1));
            aux = floor(str2double(app.info(:,2))/1000)
            aux=unique(aux)
            
            posixMin = aux-1;
            posixMax = aux+1;
            app.DateDropDown.Items=names1;
            
            
            x=app.info(str2double(app.info(:,2))/1000>=posixMin(1) & str2double(app.info(:,2))/1000<=posixMax(1),:);
            
            lum = str2double(x(x(:,4)=="Luminosity",3));
            hum = str2double(x(x(:,4)=="Humidity",3));
            tem = str2double(x(x(:,4)=="Temperature",3));
            glo = str2double(x(x(:,4)=="GlobalComfort",3));
            ther = str2double(x(x(:,4)=="ThermalComfort",3));
            lumcomf = str2double(x(x(:,4)=="LightingComfort",3));
            
            
            if ~isempty(lum) app.last_values(1)=mean(lum); app.LuminosityGauge.Value = mean(lum); end
            if ~isempty(hum) app.last_values(2)=mean(hum); app.HumidityGauge.Value = mean(hum); end
            if ~isempty(tem) app.last_values(3)=mean(tem); app.TemperatureGauge.Value = mean(tem); end
            if ~isempty(glo) app.last_values(4)=mean(glo);app.GlobalComfortGauge.Value = mean(glo); end
            if ~isempty(ther) app.last_values(5)=mean(ther);app.ThermalComfortGauge.Value = mean(ther); end
            if ~isempty(lumcomf) app.last_values(6)=mean(lumcomf);app.LightingComfortGauge.Value = mean(lumcomf); end
            %             app.HumidityGauge.Value = str2double(x(x(:,4)=="Humidity",3));
            
            %             Names = join(cellstr(cat(2,names1,names2)));
            %             str = convertCharsToStrings(names1);
            %             w=datetime(str,'InputFormat','dd-MM-yyyy HH:mm:SSSSSSSSSSSSSS');
            %             min=w-seconds(1);
            %             max=w+seconds(1);
            %
            %             posixMin=posixtime(min);
            %             posixMax=posixtime(max);
            
            %             posixTest=str2double(info(:,2)) > posixMin & str2double(info(:,2)) < posixMax;
            
            %             tf = isbetween(t,posixMin,posixMax);
            %             t=info(tf,:);
            
            %             dateMin=datetime(posixMin, 'ConvertFrom', 'posixtime');
            %             dateMax=datetime(posixMax, 'ConvertFrom', 'posixtime');
            %t=info(:,2);
            %             tf = isbetween(datetime(str2num(char(info(:,2))), 'ConvertFrom', 'posixtime'),min,max);
            %
            %             x=info(tf',:);
            
        end

        % Value changed function: MiscSwitch
        function MiscSwitchValueChanged(app, event)
            value = app.MiscSwitch.Value;
            switch value
                case 'Off'
                    app.temp.Visible='off';
                    app.temp_2.Visible='off';
                    app.co2.Visible='off';
                    app.humidity.Visible='off';
                    
                case 'On'
                    app.temp.Visible='on';
                    app.temp_2.Visible='on';
                    app.co2.Visible='on';
                    app.humidity.Visible='on';
            end
        end

        % Button pushed function: forwardButton
        function forwardButtonPushed(app, event)
            I = find(strcmp(app.DateDropDown.Items,app.DateDropDown.Value));
            if (I==length(app.DateDropDown.Items)) I=0; end;
            app.DateDropDown.Value=app.DateDropDown.Items{I+1};
            DateDropDownValueChanged(app, event);
        end

        % Button pushed function: backButton
        function backButtonPushed(app, event)
            I = find(strcmp(app.DateDropDown.Items,app.DateDropDown.Value));
            if (I==1) I=length(app.DateDropDown.Items)+1; end;
            app.DateDropDown.Value=app.DateDropDown.Items{I-1};
            DateDropDownValueChanged(app, event);
        end

        % Value changed function: DateDropDown
        function DateDropDownValueChanged(app, event)
            %             value = app.DateDropDown.Value;
            
            %             [C,ia,ic] = unique(info(:,2),'rows');
            %             info = info(ia,:);
            aux = floor(str2double(app.info(:,2))/1000)
            aux=unique(aux)
            posixMin = aux-1;
            posixMax = aux+1;
            
            %              app.sensor=app.data.Sensors_data(row_idx,:);
            % %             app.lumT_1.Text="";
            %             [C,ui,ic] = unique(app.sensor(:,3),'rows');
            %             app.info = app.sensor(ui,:);
            %             aux2 = floor(str2double(app.sensor(:,3))/1000)
            %             aux2=unique(aux2)
            %             posixMin2 = aux2-1;
            %             posixMax2 = aux2+1;
            %             x2=app.sensor(str2double(app.sensor(:,3))/1000>=posixMin2(1) & str2double(app.sensor(:,3))/1000<=posixMax2(1),:);
            %             lum1=str2double(x2(x2(:,3)==x2,4));


            app.LuminosityGauge.Value = 0;
            app.HumidityGauge.Value = 0;
            app.TemperatureGauge.Value = 0;
            app.GlobalComfortGauge.Value = 0;
            app.ThermalComfortGauge.Value = 0;
            app.LightingComfortGauge.Value = 0;
            
            I = find(strcmp(app.DateDropDown.Items,app.DateDropDown.Value));
            [row_idx, ~] = find(app.data.Sensors_data(:, 1) == string(I));
            app.sensor=app.data.Sensors_data(row_idx,:);
            if ~isempty(I)
                [c index]=min(abs(str2double(app.sensor(:,4))-posixMin(I)));
                %             app.lumT_1.Text=app.info(index,4);
                msg = sprintf('Retrieving values for feedback %s',char(app.DateDropDown.Items(I)));
                
                %                 jhText = findobj(handles.)
                app.OutputTextArea_3.Value{end+1}=msg;
                %                 var=app.OutputTextArea_3.Value;
                %                  var = sprintf('%s',char(var));
                %
                %                 app.OutputTextArea_3.Value={''};
                %                 app.OutputTextArea_3.Value=char(msg);
                %                 app.OutputTextArea_3.Value{end+1}=char(var);
                %                  pause(0.01);
                %                  jhObj = findobj(app.OutputTextArea_3);
                %                  jhObj.setCaretPosition(jhObj.getDocument.getLength);
                
                %                  scroll(app.OutputTextArea_3,msg)
                
                %                 jhObj.OutputTextArea_3.setCaretPosition();
                %                 set(app.OutputTextArea_3,'Value',{app.OutputTextArea_3, ':-)'});
                x = app.info(str2double(app.info(:,2))/1000>=posixMin(I) & str2double(app.info(:,2))/1000<=posixMax(I),:);
                lum = str2double(x(x(:,4)=="Luminosity",3));
                hum = str2double(x(x(:,4)=="Humidity",3));
                tem = str2double(x(x(:,4)=="Temperature",3));
                glo = str2double(x(x(:,4)=="GlobalComfort",3));
                ther = str2double(x(x(:,4)=="ThermalComfort",3));
                lumcomf = str2double(x(x(:,4)=="LightingComfort",3));
                
                if ~isempty(lum) app.last_values(1)=mean(lum); app.LuminosityGauge.Value = mean(lum); end
                if ~isempty(hum) app.last_values(2)=mean(hum); app.HumidityGauge.Value = mean(hum); end
                if ~isempty(tem) app.last_values(3)=mean(tem); app.TemperatureGauge.Value = mean(tem); end
                if ~isempty(glo) app.last_values(4)=mean(glo); app.GlobalComfortGauge.Value = mean(glo); end
                if ~isempty(ther) app.last_values(5)=mean(ther);app.ThermalComfortGauge.Value = mean(ther); end
                if ~isempty(lumcomf) app.last_values(6)=mean(lumcomf);app.LightingComfortGauge.Value = mean(lumcomf); end
                %                 app.OutputTextArea_3.Value = num2str(I)
            end
            
        end

        % Value changed function: ShowValuesSwitch_2
        function ShowValuesSwitch_2ValueChanged(app, event)
            value = app.ShowValuesSwitch_2.Value;
            switch value
                case 'Off'
                    app.lumT_1.Visible="off";
                    app.lumT_2.Visible="off";
                    app.lumT_3.Visible="off";
                    app.lumT_4.Visible="off";
                    app.lumT_5.Visible="off";
                    app.lumT_6.Visible="off";
                    
                    app.humT_1.Visible="off";
                    
                    app.temT_1.Visible="off";
                    app.temT_2.Visible="off";
                    
                case 'On'
                    
                    if string(app.LightingSwitch.Value)=='On'
                        
                        app.lumT_1.Visible='on';
                        app.lumT_2.Visible='on';
                        app.lumT_3.Visible='on';
                        app.lumT_4.Visible='on';
                        app.lumT_5.Visible='on';
                        app.lumT_6.Visible='on';
                        
                    end
                    
                    if string(app.MiscSwitch.Value)=='On'
                        
                        app.humT_1.Visible="on";
                        
                        app.temT_1.Visible="on";
                        app.temT_2.Visible="on";
                    end


            end
        end

        % Menu selected function: ImportdatasetMenu
        function ImportdatasetMenuSelected(app, event)
            cmdWinDoc = com.mathworks.mde.cmdwin.CmdWinDocument.getInstance;
            jString   = cmdWinDoc.getText(cmdWinDoc.getStartPosition.getOffset, ...
                cmdWinDoc.getLength);
            mString = char(jString);
            %              msg = sprintf('Retrieving experiment %s',char(value));
            
        end

        % Button pushed function: testButton
        function testButtonPushed(app, event)
            msg = 'Test performed!';

            app.OutputTextArea_2.Value{end+1}=msg;
                
            knobT=[app.TemperatureKnob.Value];
            knobL=[app.LuminosityKnob.Value];
            knobH=[app.HumidityKnob_2.Value];
            
            knobL2=[app.LuminosityKnob.Value app.LuminosityKnob.Value];
            knobL6=[app.LuminosityKnob.Value app.LuminosityKnob.Value app.LuminosityKnob.Value app.LuminosityKnob.Value app.LuminosityKnob.Value app.LuminosityKnob.Value];
            
            glo10=[app.LuminosityKnob.Value app.LuminosityKnob.Value app.LuminosityKnob.Value app.LuminosityKnob.Value app.LuminosityKnob.Value app.LuminosityKnob.Value app.TemperatureKnob.Value app.TemperatureKnob.Value app.TemperatureKnob.Value app.HumidityKnob_2.Value]
              

            yt1=ones(1);
            yt2=ones(2);
            yt6=ones(6);
            
            value1=app.TemperatureKnob_2.Value;
            value2=app.HumidityKnob_3.Value;
            value3=app.LuminosityKnob_2.Value;
            value4=app.GlobalKnob.Value;
            value5=app.ThermalKnob.Value;
            value6=app.LightingKnob.Value;
            
            machine=[string(value1) string(value2) string(value3) string(value4) string(value5) string(value6)];
            for i=1:size(machine,2)
                
                switch i
                    case 1
                        if machine(i)=="On"
                            [Ytest_Temperature1,accuracy_Temperature1,precision_Temperature1,recall_Temperature1]=testSVM(app.data.model_Temperature,knobT,yt1);
                                        app.TemperatureGauge_2.Value=Ytest_Temperature1;

                        elseif machine(i)=="Padded"
                            [Ytest_Temperature1_padded,accuracy_Temperature1_padded,precision_Temperature1_padded,recall_Temperature1_padded]=testSVM(app.data.model_Temperature_padded,knobT,yt1);
                                        app.TemperatureGauge_2.Value=Ytest_Temperature1_padded;

                        end
                        %     [Ytest_Temperature2,accuracy_Temperature2,precision_Temperature2,recall_Temperature2]=testSVM(model_Temperature2,Xtemp2,Ytemp2);
                        %     [Ytest_Temperature2_padded,accuracy_Temperature2_padded,precision_Temperature2_padded,recall_Temperature2_padded]=testSVM(model_Temperature2_padded,Xtemp2_padded,Ytemp2_padded);
                        
                    case 2
                        if machine(i)=="On"
                            [Ytest_Humidity,accuracy_Humidity,precision_Humidity,recall_Humidity]=testSVM(app.data.model_Humidity,knobH,yt1);
                                        
                                        if num2str(Ytest_Humidity)=="-Inf" 
                                             app.HumidityGauge_2.Value=5;
                                        else 
                                            app.HumidityGauge_2.Value=Ytest_Humidity;
                                        end

                        elseif machine(i)=="Padded"
                            
                        end
                        
                    case 3
                        if machine(i)=="On"
                            [Ytest_Luminosity,accuracy_Luminosity,precision_Luminosity,recall_Luminosity]=testSVM(app.data.model_Luminosity,knobL2,yt1);
                            app.LuminosityGauge_2.Value=Ytest_Luminosity;
                        elseif machine(i)=="Padded"
                            [Ytest_Luminosity_padded,accuracy_Luminosity_padded,precision_Luminosity_padded,recall_Luminosity_padded]=testSVM(app.data.model_Luminosity_padded,knobL2,yt1);
                                        app.LuminosityGauge_2.Value=Ytest_Luminosity_padded;

                        end
                        
                    case 5
                        %% Test perceptions
                        if machine(i)=="On"
                            [Ytest_Thermal,accuracy_Thermal,precision_Thermal,recall_Thermal]=testSVM(app.data.model_Thermal,knobL2,yt1);
                            if ~isnan(Ytest_Thermal) 
                                app.ThermalComfortGauge_2.Value=Ytest_Thermal; 
                            else 
                                app.ThermalComfortGauge_2.Value=5;
                            end


                        elseif machine(i)=="Padded"
                            [Ytest_Thermal_padded,accuracy_Thermal_padded,precision_Thermal_padded,recall_Thermal_padded]=testSVM(app.data.model_Thermal_padded,knobL2,yt1);
                            app.ThermalComfortGauge_2.Value=Ytest_Thermal_padded;

                        end
                        
                    case 6
                        if machine(i)=="On"
                            [Ytest_Lighting,accuracy_Lighting,precision_Lighting,recall_Lighting]=testSVM(app.data.model_Lighting,knobL6,yt1);
                             app.LightingComfortGauge_2.Value=Ytest_Lighting;

                        elseif machine(i)=="Padded"
                            [Ytest_Lighting_padded,accuracy_Lighting_padded,precision_Lighting_padded,recall_Lighting_padded]=testSVM(app.data.model_Lighting_padded,knobL6,yt1);
                            app.LightingComfortGauge_2.Value=Ytest_Lighting_padded;

                        end
                        
                    case 4
                        if machine(i)=="On"
                            [Ytest_Global,accuracy_Global,precision_Global,recall_Global]=testSVM(app.data.model_Global,glo10,yt1);
                            
                            if num2str(Ytest_Global)=="-Inf" 
                               pp.GlobalComfortGauge_2.Value=5;
                            else 
                               pp.GlobalComfortGauge_2.Value=Ytest_Global;
                            end
                        elseif machine(i)=="Padded"
                            [Ytest_Global_padded,accuracy_Global_padded,precision_Global_padded,recall_Global_padded]=testSVM(app.data.model_Global_padded,glo10,yt1);
                            app.GlobalComfortGauge_2.Value=Ytest_Global_padded;
                        end
                end
            end


%             app.TemperatureGauge_2.Value=temperature;
%             app.HumidityGauge_2.Value=humidity;
%             app.LuminosityGauge_2.Value=luminosity;
%             app.GlobalComfortGauge_2.Value=globalC;
%             app.ThermalComfortGauge_2.Value=thermalC;
%             app.LightingComfortGauge_2.Value=lightingC;
            
        end

        % Value changed function: TemperatureKnob_2
        function TemperatureKnob_2ValueChanged(app, event)
            value = app.TemperatureKnob_2.Value;
            if string(value)~='Off'
                app.lampTemperature.Color='red';
            end
            if string(value)=='Off'
                app.lampTemperature.Color='black';
                app.acuTemp.Text='';
            end
            
        end

        % Value changed function: TrainButton
        function TrainButtonValueChanged(app, event)
            value = app.TrainButton.Value;
            
            value1=app.TemperatureKnob_2.Value;
            value2=app.HumidityKnob_3.Value;
            value3=app.LuminosityKnob_2.Value;
            value4=app.GlobalKnob.Value;
            value5=app.ThermalKnob.Value;
            value6=app.LightingKnob.Value;
            

            accuracy=traindata(1,value1);
            m1=sprintf('%5.2f%%',accuracy);
            if accuracy==0 app.acuTemp.Text=''; else app.acuTemp.Text=m1; msg = sprintf('Temperature training performed with %s accuracy',m1);
            app.OutputTextArea_2.Value{end+1}=msg; app.lampTemperature.Color='green'; end
            accuracy=0;
            

            accuracy=traindata(2,value2);
            m2=sprintf('%5.2f%%',accuracy);
            if accuracy==0 app.acuHum.Text=''; else app.acuHum.Text=m2; msg = sprintf('Humidity training performed with %s accuracy',m2);
            app.OutputTextArea_2.Value{end+1}=msg; app.lampHumidity.Color='green';end
            accuracy=0;
            
            accuracy=traindata(3,value3);
            m3=sprintf('%5.2f%%',accuracy);
            if accuracy==0 app.acuLum.Text=''; else app.acuLum.Text=m3; msg = sprintf('Luminosity training performed with %s accuracy',m3);
            app.OutputTextArea_2.Value{end+1}=msg; app.lampLuminosity.Color='green';end
            accuracy=0;
            
            accuracy=traindata(4,value4);
            m4=sprintf('%5.2f%%',accuracy);
            if accuracy==0 app.acuGlob.Text=''; else app.acuGlob.Text=m4; msg = sprintf('Global Comfort training performed with %s accuracy',m4);
            app.OutputTextArea_2.Value{end+1}=msg; app.lampGlobal.Color='green';end
            accuracy=0;
            
            accuracy=traindata(5,value5);
            m5=sprintf('%5.2f%%',accuracy);
            if accuracy==0 app.acuTherm.Text=''; else app.acuTherm.Text=m5; msg = sprintf('Thermal Comfort training performed with %s accuracy',m5);
            app.OutputTextArea_2.Value{end+1}=msg; app.lampThermal.Color='green';end
            accuracy=0;
            
            accuracy=traindata(6,value6);
            m6=sprintf('%5.2f%%',accuracy);
            if accuracy==0 app.acuLight.Text=''; else app.acuLight.Text=m6; msg = sprintf('Lighting Comfort training performed with %s accuracy',m6);
            app.OutputTextArea_2.Value{end+1}=msg; app.lampLighting.Color='green';end
            
            accuracy=0;
            
            
        end

        % Value changed function: HumidityKnob_3
        function HumidityKnob_3ValueChanged(app, event)
            value = app.HumidityKnob_3.Value;
            if string(value)~='Off'
                app.lampHumidity.Color='red';
            else
                
                app.lampHumidity.Color='black';
                app.acuHum.Text='';
            end
        end

        % Value changed function: LuminosityKnob_2
        function LuminosityKnob_2ValueChanged(app, event)
            value = app.LuminosityKnob_2.Value;
            if string(value)~='Off'
                app.lampLuminosity.Color='red';
            end
            if string(value)=='Off'
                app.lampLuminosity.Color='black';
                app.acuLum.Text='';
            end
        end

        % Value changed function: GlobalKnob
        function GlobalKnobValueChanged(app, event)
            value = app.GlobalKnob.Value;
            if string(value)~='Off'
                app.lampGlobal.Color='red';
            end
            if string(value)=='Off'
                app.lampGlobal.Color='black';
                app.acuGlob.Text='';
            end
        end

        % Value changed function: ThermalKnob
        function ThermalKnobValueChanged(app, event)
            value = app.ThermalKnob.Value;
            if string(value)~='Off'
                app.lampThermal.Color='red';
            end
            if string(value)=='Off'
                app.lampThermal.Color='black';
                app.acuTherm.Text='';
            end
        end

        % Value changed function: LightingKnob
        function LightingKnobValueChanged(app, event)
            value = app.LightingKnob.Value;
            if string(value)~='Off'
                app.lampLighting.Color='red';
            end
            if string(value)=='Off'
                app.lampLighting.Color='black';
                app.acuLight.Text='';
            end
        end

        % Menu selected function: ParsedataMenu
        function ParsedataMenuSelected(app, event)
            parsedata;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SmartHome = uifigure;
            app.SmartHome.Name = 'Smart Home';
            app.FileMenu = uimenu(app.SmartHome);
            app.FileMenu.Text = 'File';
            app.ImportdatasetMenu = uimenu(app.FileMenu);
            app.ImportdatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportdatasetMenuSelected, true);
            app.ImportdatasetMenu.Text = 'Import dataset';
            app.LoaddataMenu = uimenu(app.FileMenu);
            app.LoaddataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoaddataMenuSelected, true);
            app.LoaddataMenu.Text = 'Load data';
            app.ParsedataMenu = uimenu(app.FileMenu);
            app.ParsedataMenu.MenuSelectedFcn = createCallbackFcn(app, @ParsedataMenuSelected, true);
            app.ParsedataMenu.Text = 'Parse data';
            app.TabGroup = uitabgroup(app.SmartHome);
            app.Consulta = uitab(app.TabGroup);
            app.Consulta.Title = 'Browser';
            app.Button = uibutton(app.Consulta, 'push');
            app.Button.Icon = 'background.png';
            app.Button.Text = '';
            app.ExperimentLabel = uilabel(app.Consulta);
            app.ExperimentLabel.Text = 'Experiment';
            app.ExperienciaDropDown = uidropdown(app.Consulta);
            app.ExperienciaDropDown.Items = {};
            app.ExperienciaDropDown.ValueChangedFcn = createCallbackFcn(app, @ExperienciaDropDownValueChanged, true);
            app.ExperienciaDropDown.Value = {};
            app.LightingSwitchLabel = uilabel(app.Consulta);
            app.LightingSwitchLabel.Text = 'Lighting';
            app.LightingSwitch = uiswitch(app.Consulta, 'toggle');
            app.LightingSwitch.ValueChangedFcn = createCallbackFcn(app, @LightingSwitchValueChanged, true);
            app.lamp1 = uibutton(app.Consulta, 'push');
            app.lamp1.Icon = 'Light_bulb.png';
            app.lamp1.Visible = 'off';
            app.lamp1.Text = '';
            app.lamp2 = uibutton(app.Consulta, 'push');
            app.lamp2.Icon = 'Light_bulb.png';
            app.lamp2.Visible = 'off';
            app.lamp2.Text = '';
            app.lamp3 = uibutton(app.Consulta, 'push');
            app.lamp3.Icon = 'Light_bulb.png';
            app.lamp3.Visible = 'off';
            app.lamp3.Text = '';
            app.lamp4 = uibutton(app.Consulta, 'push');
            app.lamp4.Icon = 'Light_bulb.png';
            app.lamp4.Visible = 'off';
            app.lamp4.Text = '';
            app.lamp5 = uibutton(app.Consulta, 'push');
            app.lamp5.Icon = 'Light_bulb.png';
            app.lamp5.Visible = 'off';
            app.lamp5.Text = '';
            app.lamp6 = uibutton(app.Consulta, 'push');
            app.lamp6.Icon = 'Light_bulb.png';
            app.lamp6.Visible = 'off';
            app.lamp6.Text = '';
            app.lamp7 = uibutton(app.Consulta, 'push');
            app.lamp7.Icon = 'Light_bulb.png';
            app.lamp7.Visible = 'off';
            app.lamp7.Text = '';
            app.lamp8 = uibutton(app.Consulta, 'push');
            app.lamp8.Icon = 'Light_bulb.png';
            app.lamp8.Visible = 'off';
            app.lamp8.Text = '';
            app.lamp9 = uibutton(app.Consulta, 'push');
            app.lamp9.Icon = 'Light_bulb.png';
            app.lamp9.Visible = 'off';
            app.lamp9.Text = '';
            app.lamp10 = uibutton(app.Consulta, 'push');
            app.lamp10.Icon = 'Light_bulb.png';
            app.lamp10.Visible = 'off';
            app.lamp10.Text = '';
            app.lamp11 = uibutton(app.Consulta, 'push');
            app.lamp11.Icon = 'Light_bulb.png';
            app.lamp11.Visible = 'off';
            app.lamp11.Text = '';
            app.lamp12 = uibutton(app.Consulta, 'push');
            app.lamp12.Icon = 'Light_bulb.png';
            app.lamp12.Visible = 'off';
            app.lamp12.Text = '';
            app.lamp13 = uibutton(app.Consulta, 'push');
            app.lamp13.Icon = 'Light_bulb.png';
            app.lamp13.Visible = 'off';
            app.lamp13.Text = '';
            app.lamp14 = uibutton(app.Consulta, 'push');
            app.lamp14.Icon = 'Light_bulb.png';
            app.lamp14.Visible = 'off';
            app.lamp14.Text = '';
            app.lamp15 = uibutton(app.Consulta, 'push');
            app.lamp15.Icon = 'Light_bulb.png';
            app.lamp15.Visible = 'off';
            app.lamp15.Text = '';
            app.OutputTextArea_3Label = uilabel(app.Consulta);
            app.OutputTextArea_3Label.Text = 'Output';
            app.OutputTextArea_3 = uitextarea(app.Consulta);
            app.OutputTextArea_3.Value = {'Starting up...'};
            app.PowerPlugSwitchLabel = uilabel(app.Consulta);
            app.PowerPlugSwitchLabel.Text = 'Power Plug';
            app.PowerPlugSwitch = uiswitch(app.Consulta, 'toggle');
            app.PowerPlugSwitch.ValueChangedFcn = createCallbackFcn(app, @PowerPlugSwitchValueChanged, true);
            app.plug1 = uibutton(app.Consulta, 'push');
            app.plug1.Icon = 'power-plug-icon.png';
            app.plug1.Visible = 'off';
            app.plug1.Text = '';
            app.plug2 = uibutton(app.Consulta, 'push');
            app.plug2.Icon = 'power-plug-icon.png';
            app.plug2.Visible = 'off';
            app.plug2.Text = '';
            app.plug3 = uibutton(app.Consulta, 'push');
            app.plug3.Icon = 'power-plug-icon.png';
            app.plug3.Visible = 'off';
            app.plug3.Text = '';
            app.plug4 = uibutton(app.Consulta, 'push');
            app.plug4.Icon = 'power-plug-icon.png';
            app.plug4.Visible = 'off';
            app.plug4.Text = '';
            app.plug5 = uibutton(app.Consulta, 'push');
            app.plug5.Icon = 'power-plug-icon.png';
            app.plug5.Visible = 'off';
            app.plug5.Text = '';
            app.plug6 = uibutton(app.Consulta, 'push');
            app.plug6.Icon = 'power-plug-icon.png';
            app.plug6.Visible = 'off';
            app.plug6.Text = '';
            app.plug7 = uibutton(app.Consulta, 'push');
            app.plug7.Icon = 'power-plug-icon.png';
            app.plug7.Visible = 'off';
            app.plug7.Text = '';
            app.plug8 = uibutton(app.Consulta, 'push');
            app.plug8.Icon = 'power-plug-icon.png';
            app.plug8.Visible = 'off';
            app.plug8.Text = '';
            app.plug9 = uibutton(app.Consulta, 'push');
            app.plug9.Icon = 'power-plug-icon.png';
            app.plug9.Visible = 'off';
            app.plug9.Text = '';
            app.plug10 = uibutton(app.Consulta, 'push');
            app.plug10.Icon = 'power-plug-icon.png';
            app.plug10.Visible = 'off';
            app.plug10.Text = '';
            app.plug11 = uibutton(app.Consulta, 'push');
            app.plug11.Icon = 'power-plug-icon.png';
            app.plug11.Visible = 'off';
            app.plug11.Text = '';
            app.plug12 = uibutton(app.Consulta, 'push');
            app.plug12.Icon = 'power-plug-icon.png';
            app.plug12.Visible = 'off';
            app.plug12.Text = '';
            app.ShuttersSwitchLabel = uilabel(app.Consulta);
            app.ShuttersSwitchLabel.Text = 'Shutters';
            app.ShuttersSwitch = uiswitch(app.Consulta, 'toggle');
            app.ShuttersSwitch.ValueChangedFcn = createCallbackFcn(app, @ShuttersSwitchValueChanged, true);
            app.shut1 = uibutton(app.Consulta, 'push');
            app.shut1.Icon = 'shutter.png';
            app.shut1.Visible = 'off';
            app.shut1.Text = '';
            app.shut2 = uibutton(app.Consulta, 'push');
            app.shut2.Icon = 'shutter.png';
            app.shut2.Visible = 'off';
            app.shut2.Text = '';
            app.shut3 = uibutton(app.Consulta, 'push');
            app.shut3.Icon = 'shutter.png';
            app.shut3.Visible = 'off';
            app.shut3.Text = '';
            app.shut4 = uibutton(app.Consulta, 'push');
            app.shut4.Icon = 'shutter.png';
            app.shut4.Visible = 'off';
            app.shut4.Text = '';
            app.shut5 = uibutton(app.Consulta, 'push');
            app.shut5.Icon = 'shutter.png';
            app.shut5.Visible = 'off';
            app.shut5.Text = '';
            app.shut6 = uibutton(app.Consulta, 'push');
            app.shut6.Icon = 'shutter.png';
            app.shut6.Visible = 'off';
            app.shut6.Text = '';
            app.shut7 = uibutton(app.Consulta, 'push');
            app.shut7.Icon = 'shutter.png';
            app.shut7.Visible = 'off';
            app.shut7.Text = '';
            app.shut8 = uibutton(app.Consulta, 'push');
            app.shut8.Icon = 'shutter.png';
            app.shut8.Visible = 'off';
            app.shut8.Text = '';
            app.shut9 = uibutton(app.Consulta, 'push');
            app.shut9.Icon = 'shutter.png';
            app.shut9.Visible = 'off';
            app.shut9.Text = '';
            app.shut10 = uibutton(app.Consulta, 'push');
            app.shut10.Icon = 'shutter.png';
            app.shut10.Visible = 'off';
            app.shut10.Text = '';
            app.open1 = uibutton(app.Consulta, 'push');
            app.open1.Icon = 'door_sensor.png';
            app.open1.Visible = 'off';
            app.open1.Text = '';
            app.open2 = uibutton(app.Consulta, 'push');
            app.open2.Icon = 'door_sensor.png';
            app.open2.Visible = 'off';
            app.open2.Text = '';
            app.open3 = uibutton(app.Consulta, 'push');
            app.open3.Icon = 'door_sensor.png';
            app.open3.Visible = 'off';
            app.open3.Text = '';
            app.open4 = uibutton(app.Consulta, 'push');
            app.open4.Icon = 'door_sensor.png';
            app.open4.Visible = 'off';
            app.open4.Text = '';
            app.open5 = uibutton(app.Consulta, 'push');
            app.open5.Icon = 'door_sensor.png';
            app.open5.Visible = 'off';
            app.open5.Text = '';
            app.open6 = uibutton(app.Consulta, 'push');
            app.open6.Icon = 'door_sensor.png';
            app.open6.Visible = 'off';
            app.open6.Text = '';
            app.open7 = uibutton(app.Consulta, 'push');
            app.open7.Icon = 'door_sensor.png';
            app.open7.Visible = 'off';
            app.open7.Text = '';
            app.open8 = uibutton(app.Consulta, 'push');
            app.open8.Icon = 'door_sensor.png';
            app.open8.Visible = 'off';
            app.open8.Text = '';
            app.open9 = uibutton(app.Consulta, 'push');
            app.open9.Icon = 'door_sensor.png';
            app.open9.Visible = 'off';
            app.open9.Text = '';
            app.open10 = uibutton(app.Consulta, 'push');
            app.open10.Icon = 'door_sensor.png';
            app.open10.Visible = 'off';
            app.open10.Text = '';
            app.open11 = uibutton(app.Consulta, 'push');
            app.open11.Icon = 'door_sensor.png';
            app.open11.Visible = 'off';
            app.open11.Text = '';
            app.open12 = uibutton(app.Consulta, 'push');
            app.open12.Icon = 'door_sensor.png';
            app.open12.Visible = 'off';
            app.open12.Text = '';
            app.open13 = uibutton(app.Consulta, 'push');
            app.open13.Icon = 'door_sensor.png';
            app.open13.Visible = 'off';
            app.open13.Text = '';
            app.open14 = uibutton(app.Consulta, 'push');
            app.open14.Icon = 'door_sensor.png';
            app.open14.Visible = 'off';
            app.open14.Text = '';
            app.open15 = uibutton(app.Consulta, 'push');
            app.open15.Icon = 'door_sensor.png';
            app.open15.Visible = 'off';
            app.open15.Text = '';
            app.OpeningSwitchLabel = uilabel(app.Consulta);
            app.OpeningSwitchLabel.Text = 'Opening';
            app.OpeningSwitch = uiswitch(app.Consulta, 'toggle');
            app.OpeningSwitch.ValueChangedFcn = createCallbackFcn(app, @OpeningSwitchValueChanged, true);
            app.PresenceSwitchLabel = uilabel(app.Consulta);
            app.PresenceSwitchLabel.Text = 'Presence';
            app.PresenceSwitch = uiswitch(app.Consulta, 'toggle');
            app.PresenceSwitch.ValueChangedFcn = createCallbackFcn(app, @PresenceSwitchValueChanged, true);
            app.pres1 = uibutton(app.Consulta, 'push');
            app.pres1.Icon = 'presence-detector.png';
            app.pres1.Visible = 'off';
            app.pres1.Text = '';
            app.pres2 = uibutton(app.Consulta, 'push');
            app.pres2.Icon = 'presence-detector.png';
            app.pres2.Visible = 'off';
            app.pres2.Text = '';
            app.RGBSwitchLabel = uilabel(app.Consulta);
            app.RGBSwitchLabel.Text = 'RGB';
            app.RGBSwitch = uiswitch(app.Consulta, 'toggle');
            app.RGBSwitch.ValueChangedFcn = createCallbackFcn(app, @RGBSwitchValueChanged, true);
            app.rgb1 = uibutton(app.Consulta, 'push');
            app.rgb1.Icon = 'rgb.jpg';
            app.rgb1.Visible = 'off';
            app.rgb1.Text = '';
            app.rgb2 = uibutton(app.Consulta, 'push');
            app.rgb2.Icon = 'rgb.jpg';
            app.rgb2.Visible = 'off';
            app.rgb2.Text = '';
            app.rgb3 = uibutton(app.Consulta, 'push');
            app.rgb3.Icon = 'rgb.jpg';
            app.rgb3.Visible = 'off';
            app.rgb3.Text = '';
            app.rgb4 = uibutton(app.Consulta, 'push');
            app.rgb4.Icon = 'rgb-inverse.jpg';
            app.rgb4.Visible = 'off';
            app.rgb4.Text = '';
            app.MultimediaSwitchLabel = uilabel(app.Consulta);
            app.MultimediaSwitchLabel.Text = 'Multimedia';
            app.MultimediaSwitch = uiswitch(app.Consulta, 'toggle');
            app.MultimediaSwitch.ValueChangedFcn = createCallbackFcn(app, @MultimediaSwitchValueChanged, true);
            app.mult1 = uibutton(app.Consulta, 'push');
            app.mult1.Icon = 'audio.jpg';
            app.mult1.Visible = 'off';
            app.mult1.Text = '';
            app.mult2 = uibutton(app.Consulta, 'push');
            app.mult2.Icon = 'audio.jpg';
            app.mult2.Visible = 'off';
            app.mult2.Text = '';
            app.mult3 = uibutton(app.Consulta, 'push');
            app.mult3.Icon = 'audio.jpg';
            app.mult3.Visible = 'off';
            app.mult3.Text = '';
            app.mult4 = uibutton(app.Consulta, 'push');
            app.mult4.Icon = 'tv.png';
            app.mult4.Visible = 'off';
            app.mult4.Text = '';
            app.Panel = uipanel(app.Consulta);
            app.TemperatureGaugeLabel = uilabel(app.Panel);
            app.TemperatureGaugeLabel.Text = 'Temperature';
            app.TemperatureGauge = uigauge(app.Panel, 'circular');
            app.TemperatureGauge.Limits = [0 10];
            app.HumidityGaugeLabel = uilabel(app.Panel);
            app.HumidityGaugeLabel.Text = 'Humidity';
            app.HumidityGauge = uigauge(app.Panel, 'circular');
            app.HumidityGauge.Limits = [0 10];
            app.LuminosityGaugeLabel = uilabel(app.Panel);
            app.LuminosityGaugeLabel.Text = 'Luminosity';
            app.LuminosityGauge = uigauge(app.Panel, 'circular');
            app.LuminosityGauge.Limits = [0 10];
            app.VentilationAirSpeedGaugeLabel = uilabel(app.Panel);
            app.VentilationAirSpeedGaugeLabel.Enable = 'off';
            app.VentilationAirSpeedGaugeLabel.Text = 'Ventilation / Air Speed';
            app.VentilationAirSpeedGauge = uigauge(app.Panel, 'circular');
            app.VentilationAirSpeedGauge.Limits = [0 10];
            app.VentilationAirSpeedGauge.Enable = 'off';
            app.SmellGaugeLabel = uilabel(app.Panel);
            app.SmellGaugeLabel.Enable = 'off';
            app.SmellGaugeLabel.Text = 'Smell';
            app.SmellGauge = uigauge(app.Panel, 'circular');
            app.SmellGauge.Limits = [0 10];
            app.SmellGauge.Enable = 'off';
            app.NoiseLevelGaugeLabel = uilabel(app.Panel);
            app.NoiseLevelGaugeLabel.Enable = 'off';
            app.NoiseLevelGaugeLabel.Text = 'Noise Level';
            app.NoiseLevelGauge = uigauge(app.Panel, 'circular');
            app.NoiseLevelGauge.Limits = [0 10];
            app.NoiseLevelGauge.Enable = 'off';
            app.ThermalComfortGaugeLabel = uilabel(app.Panel);
            app.ThermalComfortGaugeLabel.Text = 'Thermal Comfort';
            app.ThermalComfortGauge = uigauge(app.Panel, 'circular');
            app.ThermalComfortGauge.Limits = [0 10];
            app.GlobalComfortGaugeLabel = uilabel(app.Panel);
            app.GlobalComfortGaugeLabel.Text = 'Global Comfort';
            app.GlobalComfortGauge = uigauge(app.Panel, 'circular');
            app.GlobalComfortGauge.Limits = [0 10];
            app.AirQualityGaugeLabel = uilabel(app.Panel);
            app.AirQualityGaugeLabel.Enable = 'off';
            app.AirQualityGaugeLabel.Text = 'Air Quality';
            app.AirQualityGauge = uigauge(app.Panel, 'circular');
            app.AirQualityGauge.Limits = [0 10];
            app.AirQualityGauge.Enable = 'off';
            app.LightingComfortGaugeLabel = uilabel(app.Panel);
            app.LightingComfortGaugeLabel.Text = 'Lighting Comfort';
            app.LightingComfortGauge = uigauge(app.Panel, 'circular');
            app.LightingComfortGauge.Limits = [0 10];
            app.AcousticComfortGaugeLabel = uilabel(app.Panel);
            app.AcousticComfortGaugeLabel.Enable = 'off';
            app.AcousticComfortGaugeLabel.Text = 'Acoustic Comfort';
            app.AcousticComfortGauge = uigauge(app.Panel, 'circular');
            app.AcousticComfortGauge.Limits = [0 10];
            app.AcousticComfortGauge.Enable = 'off';
            app.ShowValuesSwitch_2Label = uilabel(app.Consulta);
            app.ShowValuesSwitch_2Label.Text = 'Show Values';
            app.ShowValuesSwitch_2 = uiswitch(app.Consulta, 'rocker');
            app.ShowValuesSwitch_2.ValueChangedFcn = createCallbackFcn(app, @ShowValuesSwitch_2ValueChanged, true);
            app.MiscLabel = uilabel(app.Consulta);
            app.MiscLabel.Text = 'Misc';
            app.MiscSwitch = uiswitch(app.Consulta, 'toggle');
            app.MiscSwitch.ValueChangedFcn = createCallbackFcn(app, @MiscSwitchValueChanged, true);
            app.temp = uibutton(app.Consulta, 'push');
            app.temp.Icon = 'thermometer.png';
            app.temp.Visible = 'off';
            app.temp.Text = '';
            app.temp_2 = uibutton(app.Consulta, 'push');
            app.temp_2.Icon = 'thermometer.png';
            app.temp_2.Visible = 'off';
            app.temp_2.Text = '';
            app.co2 = uibutton(app.Consulta, 'push');
            app.co2.Icon = 'co2.png';
            app.co2.Visible = 'off';
            app.co2.Text = '';
            app.humidity = uibutton(app.Consulta, 'push');
            app.humidity.Icon = 'humidity.png';
            app.humidity.Visible = 'off';
            app.humidity.Text = '';
            app.DateDropDownLabel = uilabel(app.Consulta);
            app.DateDropDownLabel.Text = 'Date';
            app.DateDropDown = uidropdown(app.Consulta);
            app.DateDropDown.Items = {};
            app.DateDropDown.ValueChangedFcn = createCallbackFcn(app, @DateDropDownValueChanged, true);
            app.DateDropDown.Value = {};
            app.forwardButton = uibutton(app.Consulta, 'push');
            app.forwardButton.ButtonPushedFcn = createCallbackFcn(app, @forwardButtonPushed, true);
            app.forwardButton.Text = '>';
            app.backButton = uibutton(app.Consulta, 'push');
            app.backButton.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.backButton.Text = '<';
            app.lumT_1 = uilabel(app.Consulta);
            app.lumT_1.Visible = 'off';
            app.lumT_1.Text = 'FFFE48CA9395';
            app.lumT_2 = uilabel(app.Consulta);
            app.lumT_2.Visible = 'off';
            app.lumT_2.Text = 'FFFE659CDFB3';
            app.lumT_3 = uilabel(app.Consulta);
            app.lumT_3.Visible = 'off';
            app.lumT_3.Text = 'FFFE2B37EAB9';
            app.lumT_4 = uilabel(app.Consulta);
            app.lumT_4.Visible = 'off';
            app.lumT_4.Text = 'FFFEA4ACFDCC';
            app.lumT_5 = uilabel(app.Consulta);
            app.lumT_5.Visible = 'off';
            app.lumT_5.Text = 'FFFEDC6CAA6A';
            app.lumT_6 = uilabel(app.Consulta);
            app.lumT_6.Visible = 'off';
            app.lumT_6.Text = 'FFFE8CC9BAD0';
            app.humT_1 = uilabel(app.Consulta);
            app.humT_1.Visible = 'off';
            app.humT_1.Text = 'FFFE3CE1BCAA';
            app.temT_1 = uilabel(app.Consulta);
            app.temT_1.Visible = 'off';
            app.temT_1.Text = 'FFFE9DA3A50A';
            app.temT_2 = uilabel(app.Consulta);
            app.temT_2.Visible = 'off';
            app.temT_2.Text = 'FFFEB8A8CCBB';
            app.co2T_1 = uilabel(app.Consulta);
            app.co2T_1.Visible = 'off';
            app.co2T_1.Text = 'FFFE97678AAD';
            app.Teste = uitab(app.TabGroup);
            app.Teste.Title = 'SVM';
            app.TrainingPanel = uipanel(app.Teste);
            app.TrainingPanel.Title = 'Training';
            app.OutputTextArea_2Label = uilabel(app.TrainingPanel);
            app.OutputTextArea_2Label.Text = 'Output';
            app.OutputTextArea_2 = uitextarea(app.TrainingPanel);
            app.TemperatureKnob_2Label = uilabel(app.TrainingPanel);
            app.TemperatureKnob_2Label.Text = 'Temperature';
            app.TemperatureKnob_2 = uiknob(app.TrainingPanel, 'discrete');
            app.TemperatureKnob_2.Items = {'Off', 'Padded', 'On'};
            app.TemperatureKnob_2.ValueChangedFcn = createCallbackFcn(app, @TemperatureKnob_2ValueChanged, true);
            app.HumidityKnob_3Label = uilabel(app.TrainingPanel);
            app.HumidityKnob_3Label.Text = 'Humidity';
            app.HumidityKnob_3 = uiknob(app.TrainingPanel, 'discrete');
            app.HumidityKnob_3.Items = {'Off', 'On'};
            app.HumidityKnob_3.ValueChangedFcn = createCallbackFcn(app, @HumidityKnob_3ValueChanged, true);
            app.LuminosityKnob_2Label = uilabel(app.TrainingPanel);
            app.LuminosityKnob_2Label.Text = 'Luminosity';
            app.LuminosityKnob_2 = uiknob(app.TrainingPanel, 'discrete');
            app.LuminosityKnob_2.Items = {'Off', 'Padded', 'On'};
            app.LuminosityKnob_2.ValueChangedFcn = createCallbackFcn(app, @LuminosityKnob_2ValueChanged, true);
            app.GlobalKnobLabel = uilabel(app.TrainingPanel);
            app.GlobalKnobLabel.Text = 'Global';
            app.GlobalKnob = uiknob(app.TrainingPanel, 'discrete');
            app.GlobalKnob.Items = {'Off', 'Padded', 'On'};
            app.GlobalKnob.ValueChangedFcn = createCallbackFcn(app, @GlobalKnobValueChanged, true);
            app.ThermalKnobLabel = uilabel(app.TrainingPanel);
            app.ThermalKnobLabel.Text = 'Thermal';
            app.ThermalKnob = uiknob(app.TrainingPanel, 'discrete');
            app.ThermalKnob.Items = {'Off', 'Padded', 'On'};
            app.ThermalKnob.ValueChangedFcn = createCallbackFcn(app, @ThermalKnobValueChanged, true);
            app.LightingKnobLabel = uilabel(app.TrainingPanel);
            app.LightingKnobLabel.Text = 'Lighting';
            app.LightingKnob = uiknob(app.TrainingPanel, 'discrete');
            app.LightingKnob.Items = {'Off', 'Padded', 'On'};
            app.LightingKnob.ValueChangedFcn = createCallbackFcn(app, @LightingKnobValueChanged, true);
            app.Label = uilabel(app.TrainingPanel);
            app.Label.Text = '';
            app.lampLighting = uilamp(app.TrainingPanel);
            app.Lamp_2Label = uilabel(app.TrainingPanel);
            app.Lamp_2Label.Text = '';
            app.lampTemperature = uilamp(app.TrainingPanel);
            app.Lamp_3Label = uilabel(app.TrainingPanel);
            app.Lamp_3Label.Text = '';
            app.lampHumidity = uilamp(app.TrainingPanel);
            app.Lamp_4Label = uilabel(app.TrainingPanel);
            app.Lamp_4Label.Text = '';
            app.lampLuminosity = uilamp(app.TrainingPanel);
            app.Lamp_5Label = uilabel(app.TrainingPanel);
            app.Lamp_5Label.Text = '';
            app.lampGlobal = uilamp(app.TrainingPanel);
            app.Lamp_6Label = uilabel(app.TrainingPanel);
            app.Lamp_6Label.Text = '';
            app.lampThermal = uilamp(app.TrainingPanel);
            app.acuTemp = uilabel(app.TrainingPanel);
            app.acuTemp.Text = '';
            app.acuHum = uilabel(app.TrainingPanel);
            app.acuHum.Text = '';
            app.acuLum = uilabel(app.TrainingPanel);
            app.acuLum.Text = '';
            app.acuGlob = uilabel(app.TrainingPanel);
            app.acuGlob.Text = '';
            app.acuTherm = uilabel(app.TrainingPanel);
            app.acuTherm.Text = '';
            app.acuLight = uilabel(app.TrainingPanel);
            app.acuLight.Text = '';
            app.TestingparametersPanel = uipanel(app.Teste);
            app.TestingparametersPanel.Title = 'Testing parameters';
            app.TemperatureKnobLabel = uilabel(app.TestingparametersPanel);
            app.TemperatureKnobLabel.Text = 'Temperature';
            app.TemperatureKnob = uiknob(app.TestingparametersPanel, 'continuous');
            app.TemperatureKnob.Limits = [-15 50];
            app.TemperatureKnob.Value = 20;
            app.HumidityKnob_2Label = uilabel(app.TestingparametersPanel);
            app.HumidityKnob_2Label.Text = 'Humidity';
            app.HumidityKnob_2 = uiknob(app.TestingparametersPanel, 'continuous');
            app.HumidityKnob_2.Value = 15;
            app.LuminosityKnobLabel = uilabel(app.TestingparametersPanel);
            app.LuminosityKnobLabel.Text = 'Luminosity';
            app.LuminosityKnob = uiknob(app.TestingparametersPanel, 'continuous');
            app.LuminosityKnob.Value = 15;
            app.testButton = uibutton(app.Teste, 'push');
            app.testButton.ButtonPushedFcn = createCallbackFcn(app, @testButtonPushed, true);
            app.testButton.Icon = 'test.png';
            app.testButton.Text = '';
            app.Panel_2 = uipanel(app.Teste);
            app.TemperatureGauge_2Label = uilabel(app.Panel_2);
            app.TemperatureGauge_2Label.Text = 'Temperature';
            app.TemperatureGauge_2 = uigauge(app.Panel_2, 'circular');
            app.TemperatureGauge_2.Limits = [0 10];
            app.HumidityGauge_2Label = uilabel(app.Panel_2);
            app.HumidityGauge_2Label.Text = 'Humidity';
            app.HumidityGauge_2 = uigauge(app.Panel_2, 'circular');
            app.HumidityGauge_2.Limits = [0 10];
            app.LuminosityGauge_2Label = uilabel(app.Panel_2);
            app.LuminosityGauge_2Label.Text = 'Luminosity';
            app.LuminosityGauge_2 = uigauge(app.Panel_2, 'circular');
            app.LuminosityGauge_2.Limits = [0 10];
            app.VentilationAirSpeedGauge_2Label = uilabel(app.Panel_2);
            app.VentilationAirSpeedGauge_2Label.Enable = 'off';
            app.VentilationAirSpeedGauge_2Label.Text = 'Ventilation / Air Speed';
            app.VentilationAirSpeedGauge_2 = uigauge(app.Panel_2, 'circular');
            app.VentilationAirSpeedGauge_2.Limits = [0 10];
            app.VentilationAirSpeedGauge_2.Enable = 'off';
            app.SmellGauge_2Label = uilabel(app.Panel_2);
            app.SmellGauge_2Label.Enable = 'off';
            app.SmellGauge_2Label.Text = 'Smell';
            app.SmellGauge_2 = uigauge(app.Panel_2, 'circular');
            app.SmellGauge_2.Limits = [0 10];
            app.SmellGauge_2.Enable = 'off';
            app.NoiseLevelGauge_2Label = uilabel(app.Panel_2);
            app.NoiseLevelGauge_2Label.Enable = 'off';
            app.NoiseLevelGauge_2Label.Text = 'Noise Level';
            app.NoiseLevelGauge_2 = uigauge(app.Panel_2, 'circular');
            app.NoiseLevelGauge_2.Limits = [0 10];
            app.NoiseLevelGauge_2.Enable = 'off';
            app.ThermalComfortGauge_2Label = uilabel(app.Panel_2);
            app.ThermalComfortGauge_2Label.Text = 'Thermal Comfort';
            app.ThermalComfortGauge_2 = uigauge(app.Panel_2, 'circular');
            app.ThermalComfortGauge_2.Limits = [0 10];
            app.GlobalComfortGauge_2Label = uilabel(app.Panel_2);
            app.GlobalComfortGauge_2Label.Text = 'Global Comfort';
            app.GlobalComfortGauge_2 = uigauge(app.Panel_2, 'circular');
            app.GlobalComfortGauge_2.Limits = [0 10];
            app.AirQualityGauge_2Label = uilabel(app.Panel_2);
            app.AirQualityGauge_2Label.Enable = 'off';
            app.AirQualityGauge_2Label.Text = 'Air Quality';
            app.AirQualityGauge_2 = uigauge(app.Panel_2, 'circular');
            app.AirQualityGauge_2.Limits = [0 10];
            app.AirQualityGauge_2.Enable = 'off';
            app.LightingComfortGauge_2Label = uilabel(app.Panel_2);
            app.LightingComfortGauge_2Label.Text = 'Lighting Comfort';
            app.LightingComfortGauge_2 = uigauge(app.Panel_2, 'circular');
            app.LightingComfortGauge_2.Limits = [0 10];
            app.AcousticComfortGauge_2Label = uilabel(app.Panel_2);
            app.AcousticComfortGauge_2Label.Enable = 'off';
            app.AcousticComfortGauge_2Label.Text = 'Acoustic Comfort';
            app.AcousticComfortGauge_2 = uigauge(app.Panel_2, 'circular');
            app.AcousticComfortGauge_2.Limits = [0 10];
            app.AcousticComfortGauge_2.Enable = 'off';
            app.TrainButton = uibutton(app.Teste, 'state');
            app.TrainButton.ValueChangedFcn = createCallbackFcn(app, @TrainButtonValueChanged, true);
            app.TrainButton.Text = 'Train';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FutureHome

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SmartHome)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SmartHome)
        end
    end
end